classdef appUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        AlgoritmoGeneticoLabel    matlab.ui.control.Label
        GUILabel                  matlab.ui.control.Label
        CalcularButton            matlab.ui.control.Button
        LimpiarButton             matlab.ui.control.Button
        EntradasPanel             matlab.ui.container.Panel
        IndividuosEditFieldLabel  matlab.ui.control.Label
        IndividuosEditField       matlab.ui.control.NumericEditField
        LmitesLabel               matlab.ui.control.Label
        LimInfEditField           matlab.ui.control.NumericEditField
        LimSupEditField           matlab.ui.control.NumericEditField
        Divider                   matlab.ui.control.Label
        TasamutuacinLabel         matlab.ui.control.Label
        TasaMutacionEditField     matlab.ui.control.NumericEditField
        LimSupMutacionEditField   matlab.ui.control.NumericEditField
        Divider_2                 matlab.ui.control.Label
        RangodemutuacinLabel      matlab.ui.control.Label
        LimInfMutacionEditField   matlab.ui.control.NumericEditField
        FrmulaEditFieldLabel      matlab.ui.control.Label
        FrmulaEditField           matlab.ui.control.NumericEditField
        IteracionesLabel          matlab.ui.control.Label
        IteracionesEditField      matlab.ui.control.NumericEditField
        FuncionListBoxLabel       matlab.ui.control.Label
        FuncionListBox            matlab.ui.control.ListBox
        DimensionesButtonGroup    matlab.ui.container.ButtonGroup
        Button                    matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        RightPanel                matlab.ui.container.Panel
        GraficasPanel             matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_3)
            % Calculate the monthly payment
            individuos = app.IndividuosEditField.Value;
            liminf = app.LimInfEditField.Value;
            limsup = app.LimSupEditField.Value;
            iteraciones = app.IteracionesEditField.Value;
            liminfmut = app.LimInfMutacionEditField.Value;
            limsupmut = app.LimSupMutacionEditField.Value;
            tasamut = app.TasaMutacionEditField.Value;
                %Dimensiones
            if app.DimensionesButtonGroup == true
                dimensiones = 2;
            else
                dimensiones = 3;
            end
            formula = app.FrmulaEditField.Value;
            
            
            %% Parametros
            % # Individuos en la poblacion
            inp.tam = individuos;
            % Valores Minimos y Maximos
            inp.vMin= liminf;
            inp.vMax= limsup;
            % Numero de Iteraciones
            inp.numIte = iteraciones;
            % Mutuacion
            inp.TasaMut = tasamut;
            inp.MinMut = liminfmut;
            inp.MaxMut = limsupmut;
            % Cantidad de dimensiones
            inp.NumVari = dimensiones;
            % idBfm
            inp.idBfm = formula;
            
            %% Algoritmo Genetico
             [ MejorValor, PeorValor, PromedioValor, Sol] = AlogoritmoGenetico(inp);
             %[Sol, ~] = AlogoritmoGenetico(inp);
             
            %Gráfica 2D
            hold (app.UIAxes, 'on'); grid (app.UIAxes, 'on');
            xlabel(app.UIAxes,'Iteracion'); ylabel(app.UIAxes,'Valores');
            plot(app.UIAxes,MejorValor); 
            plot(app.UIAxes,PeorValor);
            plot(app.UIAxes,PromedioValor);
            legend(app.UIAxes,'Mejor valor','Peor valor','Valor promedio')
            hold (app.UIAxes, 'off');
            
            %Gráfica 3D
            comet3(app.UIAxes_3,Sol(:,1), Sol(:,2),Sol(:,3));
            %hold (app.UIAxes_3, 'on'); 
            grid(app.UIAxes_3, 'on');
            xlabel(app.UIAxes_3,'x')
            ylabel(app.UIAxes_3,'y')
            zlabel(app.UIAxes_3,'z')
            %hold (app.UIAxes, 'off');
        end

        % Selection changed function: DimensionesButtonGroup
        function DimensionesButtonGroupSelectionChanged(app, event)
            selectedButton = app.DimensionesButtonGroup.SelectedObject;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes_3)
            app.IndividuosEditField.Value = 0;
            app.LimInfEditField.Value = 0;
            app.LimSupEditField.Value = 0;
            app.IteracionesEditField.Value = 0;
            app.LimInfMutacionEditField.Value = 0;
            app.LimSupMutacionEditField.Value = 0;
            app.TasaMutacionEditField.Value = 0;
            app.FrmulaEditField.Value = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {618, 618};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AlgoritmoGeneticoLabel = uilabel(app.LeftPanel);
            app.AlgoritmoGeneticoLabel.Text = 'Algoritmo Genetico';
            app.GUILabel = uilabel(app.LeftPanel);
            app.GUILabel.Text = 'GUI';
            app.CalcularButton = uibutton(app.LeftPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.EntradasPanel = uipanel(app.LeftPanel);
            app.EntradasPanel.Title = 'Entradas';
            app.IndividuosEditFieldLabel = uilabel(app.EntradasPanel);
            app.IndividuosEditFieldLabel.Text = 'Individuos:';
            app.IndividuosEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.LmitesLabel = uilabel(app.EntradasPanel);
            app.LmitesLabel.Text = 'Límites:';
            app.LimInfEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.LimSupEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.Divider = uilabel(app.EntradasPanel);
            app.Divider.Text = '—';
            app.TasamutuacinLabel = uilabel(app.EntradasPanel);
            app.TasamutuacinLabel.Text = 'Tasa mutuación:';
            app.TasaMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.LimSupMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.Divider_2 = uilabel(app.EntradasPanel);
            app.Divider_2.Text = '—';
            app.RangodemutuacinLabel = uilabel(app.EntradasPanel);
            app.RangodemutuacinLabel.Text = 'Rango de mutuación:';
            app.LimInfMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.FrmulaEditFieldLabel = uilabel(app.EntradasPanel);
            app.FrmulaEditFieldLabel.Text = 'Fórmula:';
            app.FrmulaEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.IteracionesLabel = uilabel(app.EntradasPanel);
            app.IteracionesLabel.Text = 'Iteraciones ';
            app.IteracionesEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.FuncionListBoxLabel = uilabel(app.EntradasPanel);
            app.FuncionListBoxLabel.Text = 'Funcion';
            app.FuncionListBox = uilistbox(app.EntradasPanel);
            app.DimensionesButtonGroup = uibuttongroup(app.EntradasPanel);
            app.DimensionesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimensionesButtonGroupSelectionChanged, true);
            app.DimensionesButtonGroup.Title = 'Dimensiones:';
            app.Button = uiradiobutton(app.DimensionesButtonGroup);
            app.Button.Text = '2';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.DimensionesButtonGroup);
            app.Button_2.Text = '3';
            app.RightPanel = uipanel(app.GridLayout);
            app.GraficasPanel = uipanel(app.RightPanel);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Valores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.GraficasPanel);
            title(app.UIAxes_3, 'Agentes')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end