classdef appUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        AlgoritmoGeneticoLabel   matlab.ui.control.Label
        GUILabel                 matlab.ui.control.Label
        CalcularButton           matlab.ui.control.Button
        LimpiarButton            matlab.ui.control.Button
        EntradasPanel            matlab.ui.container.Panel
        LmitesLabel              matlab.ui.control.Label
        LimInfEditField          matlab.ui.control.NumericEditField
        LimSupEditField          matlab.ui.control.NumericEditField
        Divider                  matlab.ui.control.Label
        TasamutuacinLabel        matlab.ui.control.Label
        TasaMutacionEditField    matlab.ui.control.NumericEditField
        LimSupMutacionEditField  matlab.ui.control.NumericEditField
        Divider_2                matlab.ui.control.Label
        RangodemutuacinLabel     matlab.ui.control.Label
        LimInfMutacionEditField  matlab.ui.control.NumericEditField
        IteracionesLabel         matlab.ui.control.Label
        IteracionesEditField     matlab.ui.control.NumericEditField
        FuncionListBoxLabel      matlab.ui.control.Label
        FuncionListBox           matlab.ui.control.ListBox
        DimensionesButtonGroup   matlab.ui.container.ButtonGroup
        Button                   matlab.ui.control.RadioButton
        Button_2                 matlab.ui.control.RadioButton
        IndividuosSpinnerLabel   matlab.ui.control.Label
        IndividuosEditField      matlab.ui.control.Spinner
        RightPanel               matlab.ui.container.Panel
        GraficasPanel            matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        TextArea                 matlab.ui.control.TextArea
        TitleMethod              matlab.ui.control.Label
        Label_1                  matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_3,'reset');
            app.TitleMethod.Text = '';
            app.TextArea.Visible = true;
            app.TextArea.Value = 'Corriendo..';
            app.Label_1.Text = "";
            app.Label_2.Text = "";
            app.Label_3.Text = "";
            app.Label_4.Text = "";
            
            
            individuos = app.IndividuosEditField.Value;
            liminf = app.LimInfEditField.Value;
            limsup = app.LimSupEditField.Value;
            iteraciones = app.IteracionesEditField.Value;
            liminfmut = app.LimInfMutacionEditField.Value;
            limsupmut = app.LimSupMutacionEditField.Value;
            tasamut = app.TasaMutacionEditField.Value;
                %Dimensiones
            if app.Button.Value == true 
                dimensiones = 2;
            else
                dimensiones = 3;
            end
            x = char(app.FuncionListBox.Value);
            formula = str2num(x); %#ok<*ST2NM> 
           
            %% Parametros
            % # Individuos en la poblacion
            inp.tam = individuos;
            % Valores Minimos y Maximos
            inp.vMin= liminf;
            inp.vMax= limsup;
            % Numero de Iteraciones
            inp.numIte = iteraciones;
            % Mutuacion
            inp.TasaMut = tasamut;
            inp.MinMut = liminfmut;
            inp.MaxMut = limsupmut;
            % Cantidad de dimensiones
            inp.NumVari = dimensiones;
            % idBfm
            inp.idBfm = formula;
            
            if(inp.vMin >= inp.vMax)
                app.TextArea.Value = 'ERROR Rango en limites';
            elseif(inp.MinMut >= inp.MaxMut)
                app.TextArea.Value = 'ERROR Rango en Mutuacion';
            else
            %% Algoritmo Genetico
           [ MejorValor, PeorValor, PromedioValor, Poblacion, Solucion] = AlogoritmoGenetico(inp);
             %[Sol, ~] = AlogoritmoGenetico(inp);
                           
           [~, Details] = bfm(inp.idBfm, rand(3));
           app.TitleMethod.Text = Details.Name;
            %Gráfica 2D
            hold (app.UIAxes, 'on'); grid (app.UIAxes, 'on');
            xlabel(app.UIAxes,'Iteracion'); ylabel(app.UIAxes,'Valores');
            plot(app.UIAxes,MejorValor); 
            plot(app.UIAxes,PeorValor);
            plot(app.UIAxes,PromedioValor);
            legend(app.UIAxes,'Mejor valor','Peor valor','Valor promedio')
            hold (app.UIAxes, 'off');
            
            %Gráfica 3D
           if (inp.NumVari == 2)
               hold (app.UIAxes_3, 'on'); 
               interval = (inp.vMax - inp.vMin) / 10;
               [X,Y] = meshgrid(-inp.vMax:interval:inp.vMax);
               L = length(X);
               Z = zeros(L);
               for i=1:L
                   for k=1:L
                        Z(i,k) = bfm(inp.idBfm,[X(i,k) Y(i,k)]);
                   end
               end
               mesh(app.UIAxes_3,X,Y,Z,'edgecolor', 'b');
               scatter3(app.UIAxes_3,Poblacion(:,1),Poblacion(:,2),Poblacion(:,3),'R');
               hold (app.UIAxes, 'off');
               app.Label_1.Text = "X = " +  round(10^4*Solucion(1,1))/10^4;
               app.Label_2.Text = "Y = " +  round(10^4*Solucion(1,2))/10^4;
               app.Label_4.Text = "Valor = " + round(10^4*Solucion(1,3))/10^4;
           else
               scatter3(app.UIAxes_3,Poblacion(:,1),Poblacion(:,2),Poblacion(:,3),10,Poblacion(:,4));
               colorbar(app.UIAxes_3);
               app.Label_1.Text = "X = " +  round(10^4*Solucion(1,1))/10^4;
               app.Label_2.Text = "Y = " +  round(10^4*Solucion(1,2))/10^4;
               app.Label_3.Text = "Z = " +  round(10^4*Solucion(1,3))/10^4;
               app.Label_4.Text = "Valor = " +  round(10^4*Solucion(1,4))/10^4;
           end 
            view(app.UIAxes_3,3);
            grid(app.UIAxes_3, 'on');
            xlabel(app.UIAxes_3,'x');
            ylabel(app.UIAxes_3,'y');
            zlabel(app.UIAxes_3,'z');
           app.TextArea.Value = 'Exito!!';
           pause(3);
           app.TextArea.Visible = false;          
           end 
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            cla(app.UIAxes_3,'reset');
            app.TextArea.Visible = false;
            app.TextArea.Value = '';
            app.TitleMethod.Text = '';
            app.IndividuosEditField.Value = 2;
            app.LimInfEditField.Value = 0;
            app.LimSupEditField.Value = 0;
            app.IteracionesEditField.Value = 1;
            app.LimInfMutacionEditField.Value = 0;
            app.LimSupMutacionEditField.Value = 0;
            app.TasaMutacionEditField.Value = 0;
            app.Label_1.Text = "";
            app.Label_2.Text = "";
            app.Label_3.Text = "";
            app.Label_4.Text = "";
        end

        % Value changed function: FuncionListBox
        function FuncionListBoxValueChanged(app, event)
            x = char(app.FuncionListBox.Value);
            value = str2num(x);
            [~, Details] = bfm(value, rand(3));
            if(Details.MaxDimensions < 3 && Details.MaxDimensions ~= 0)
                set(app.Button_2,'Enable','off') 
                app.Button.Value = true;
            else 
                set(app.Button_2,'Enable','on') 
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {618, 618};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {226, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AlgoritmoGeneticoLabel = uilabel(app.LeftPanel);
            app.AlgoritmoGeneticoLabel.Text = 'Algoritmo Genetico';
            app.GUILabel = uilabel(app.LeftPanel);
            app.GUILabel.Text = 'GUI';
            app.CalcularButton = uibutton(app.LeftPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.LimpiarButton = uibutton(app.LeftPanel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.EntradasPanel = uipanel(app.LeftPanel);
            app.EntradasPanel.Title = 'Entradas';
            app.LmitesLabel = uilabel(app.EntradasPanel);
            app.LmitesLabel.Text = 'Límites:';
            app.LimInfEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.LimSupEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.Divider = uilabel(app.EntradasPanel);
            app.Divider.Text = '—';
            app.TasamutuacinLabel = uilabel(app.EntradasPanel);
            app.TasamutuacinLabel.Text = 'Tasa mutuación:';
            app.TasaMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.TasaMutacionEditField.Limits = [0 1];
            app.LimSupMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.Divider_2 = uilabel(app.EntradasPanel);
            app.Divider_2.Text = '—';
            app.RangodemutuacinLabel = uilabel(app.EntradasPanel);
            app.RangodemutuacinLabel.Text = 'Rango de mutuación:';
            app.LimInfMutacionEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.IteracionesLabel = uilabel(app.EntradasPanel);
            app.IteracionesLabel.Text = 'Iteraciones ';
            app.IteracionesEditField = uieditfield(app.EntradasPanel, 'numeric');
            app.IteracionesEditField.Limits = [1 10000];
            app.IteracionesEditField.RoundFractionalValues = 'on';
            app.IteracionesEditField.Value = 1;
            app.FuncionListBoxLabel = uilabel(app.EntradasPanel);
            app.FuncionListBoxLabel.Text = 'Funcion';
            app.FuncionListBox = uilistbox(app.EntradasPanel);
            app.FuncionListBox.Items = {'1. Cosine Mixture', '2. Bukin 2', '3. Keane', '4. Mishra 2', '5. Trigonometric 1', '6. Schmidt Vetters', '7. Exponential', '8. Hosaki', '9. Giunta', '10. Quadratic', '11. Schwefel 2', '12. Sphere', '13. Camel - Six Hump', '14. Parsopoulos', '15. Deb 1', '16. Cross in Tray', '17. Colville', '18. Needle Eye', '19. Branin 1', '20. Beale', '21. Alpine 1', '22. Rana', '23. Bird', '24. Step', '25. Wayburn Seader 1', '26. Box Betts Quadratic Sum', '27. Bartels Conn', '28. Cube', '29. Ackley 1', '31. Trid 6', '32. Hansen', '33. Xor', '34. Ursem Waves', '35. Chichinadze', '36. Rastrigin', '37. Helical Valley', '38. Paviani', '39. Powell 4', '40. Weierstrass', '41. Watson', '42. Drop Wave', '43. deVilliers Glasser 1', '44. Stochastic', '45. Gulf Research Problem', '46. Salomon', '47. Griewank', '48. Whitley', '49. Damavandi', '50. deVilliers Glasser 2', '51. 2n minima'};
            app.FuncionListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51'};
            app.FuncionListBox.ValueChangedFcn = createCallbackFcn(app, @FuncionListBoxValueChanged, true);
            app.FuncionListBox.Value = '1';
            app.DimensionesButtonGroup = uibuttongroup(app.EntradasPanel);
            app.DimensionesButtonGroup.Title = 'Dimensiones:';
            app.Button = uiradiobutton(app.DimensionesButtonGroup);
            app.Button.Text = '2';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.DimensionesButtonGroup);
            app.Button_2.Text = '3';
            app.IndividuosSpinnerLabel = uilabel(app.EntradasPanel);
            app.IndividuosSpinnerLabel.Text = 'Individuos';
            app.IndividuosEditField = uispinner(app.EntradasPanel);
            app.IndividuosEditField.Limits = [2 Inf];
            app.IndividuosEditField.Value = 2;
            app.RightPanel = uipanel(app.GridLayout);
            app.GraficasPanel = uipanel(app.RightPanel);
            app.GraficasPanel.Title = 'Graficas';
            app.UIAxes = uiaxes(app.GraficasPanel);
            title(app.UIAxes, 'Valores')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_3 = uiaxes(app.GraficasPanel);
            title(app.UIAxes_3, 'Agentes')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.TextArea = uitextarea(app.GraficasPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TitleMethod = uilabel(app.GraficasPanel);
            app.TitleMethod.Text = '';
            app.Label_1 = uilabel(app.GraficasPanel);
            app.Label_1.Text = {''; ''};
            app.Label_2 = uilabel(app.GraficasPanel);
            app.Label_2.Text = {''; ''};
            app.Label_3 = uilabel(app.GraficasPanel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.GraficasPanel);
            app.Label_4.Text = {''; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end